classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EpilepsiaeSeizuresPredictorandDetectorLabel  matlab.ui.control.Label
        NeuralNetworkConfigurationsPanel  matlab.ui.container.Panel
        TrainAELamp                     matlab.ui.control.Lamp
        TrainAEButton                   matlab.ui.control.Button
        TrainNNLamp                     matlab.ui.control.Lamp
        TrainNNButton                   matlab.ui.control.Button
        ValidationDatasetDropDown       matlab.ui.control.DropDown
        TrainingDatasetDropDown         matlab.ui.control.DropDown
        AutoencoderChooseFileButton     matlab.ui.control.Button
        IconInfoAutoencoder             matlab.ui.control.Image
        AutoencoderLabel                matlab.ui.control.Label
        ValidationDatasetDropDownLabel  matlab.ui.control.Label
        TrainingDatasetDropDownLabel    matlab.ui.control.Label
        UseautoencoderSwitchLabel       matlab.ui.control.Label
        UseautoencoderSwitch            matlab.ui.control.Switch
        IconInfoNN                      matlab.ui.control.Image
        NNChooseFileButton              matlab.ui.control.Button
        ShallowDeepNNLabel              matlab.ui.control.Label
        PerformanceConfigurationsPanel  matlab.ui.container.Panel
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        NumberofoccurrencesEditField    matlab.ui.control.NumericEditField
        NumberofoccurrencesEditFieldLabel  matlab.ui.control.Label
        PredictionLamp                  matlab.ui.control.Lamp
        PredictionButton                matlab.ui.control.Button
        DetectionLamp                   matlab.ui.control.Lamp
        DetectionButton                 matlab.ui.control.Button
        TestingDatasetDropDown          matlab.ui.control.DropDown
        TestingDatasetDropDownLabel     matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        NNOptionsTextArea               matlab.ui.control.TextArea
        ValueErrorTextArea              matlab.ui.control.TextArea
        AccuracyTextArea                matlab.ui.control.TextArea
        AccuracyTextAreaLabel           matlab.ui.control.Label
        ValueErrorTextAreaLabel         matlab.ui.control.Label
        SpecificityTextAreaLabel        matlab.ui.control.Label
        SensitivityTextArea             matlab.ui.control.TextArea
        SensitivityTextAreaLabel        matlab.ui.control.Label
        SpecificityTextArea             matlab.ui.control.TextArea
        NNOptionsTextAreaLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        MiguelAntnioGabrieldeAlmeidaFaria2019216809Label  matlab.ui.control.Label
        SanchoAmaralSimes2019217590Label  matlab.ui.control.Label
        MEISIMachineLearning20222023Label  matlab.ui.control.Label
        UniversityofCoimbraLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        nnFile = "";
        autoencoderFile = "";
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PredictionButton.Enable = "off";
            app.DetectionButton.Enable = "off";
            app.PredictionLamp.Color = "red";
            app.DetectionLamp.Color = "red";
            app.TrainNNButton.Enable = "off";
            app.TrainNNLamp.Color = "red";
            app.TrainAEButton.Enable = "off";
            app.TrainAELamp.Color = "red";
            
            app.IconInfoNN.Tooltip = "Name format: <valError>_<nnType>_<trainDataset>-<classBalancingType>";
            app.IconInfoAutoencoder.Tooltip = "Name format: <valError>_<aeType>_<trainDataset>-<classBalancingType>";
            
            app.AutoencoderLabel.Visible = "off";
            app.IconInfoAutoencoder.Visible = "off";
            app.AutoencoderChooseFileButton.Visible = "off";
            app.TrainAEButton.Visible = "off";
            app.TrainAELamp.Visible = "off";

            app.NNOptionsTextArea.Editable = "off";
            app.ValueErrorTextArea.Editable = "off";
            app.AccuracyTextArea.Editable = "off";
            app.SensitivityTextArea.Editable = "off";
            app.SpecificityTextArea.Editable = "off";
        end

        % Button pushed function: PredictionButton
        function PredictionButtonPushed(app, event)
            app.PredictionLamp.Color = "green";
            drawnow()

            nn_path = app.nnFile;
            test_dataset = app.TestingDatasetDropDown.Value;
            td_path = ESPConst.PATH_DATASET_PREPROCESSED + test_dataset + "-0" + ESPConst.EXTENSION_DATA;
            num_o = app.NumberofoccurrencesEditField.Value;
            threshold = app.ThresholdEditField.Value;

            x = getClassifierPerformance(nn_path, td_path, "prediction", num_o, threshold);
            app.NNOptionsTextArea.Value = "GradientDecayFactor: " + string(x.options.GradientDecayFactor) + newline + ...
                                            "SquaredGradientDecayFactor: " + string(x.options.SquaredGradientDecayFactor) + newline + ...
                                            "Epsilon: " + string(x.options.Epsilon) + newline + ...
                                            "InitialLearnRate: " + string(x.options.InitialLearnRate) + newline + ...
                                            "LearnRateDropFactor: " + string(x.options.LearnRateDropFactor) + newline + ...
                                            "LearnRateDropPeriod: " + string(x.options.LearnRateDropPeriod) + newline + ...
                                            "L2Regularization: " + string(x.options.L2Regularization) + newline + ...
                                            "MaxEpochs: " + string(x.options.MaxEpochs)+ newline + ...
                                            "MiniBatchSize: " + string(x.options.MiniBatchSize);
            app.ValueErrorTextArea.Value = string(x.valError);
            app.AccuracyTextArea.Value = string(x.accuracy);
            app.SensitivityTextArea.Value = string(x.SE);
            app.SpecificityTextArea.Value = string(x.SP);

            app.PredictionLamp.Color = "red";
        end

        % Button pushed function: DetectionButton
        function DetectionButtonPushed(app, event)
            app.DetectionLamp.Color = "green";
            drawnow()

            nn_path = app.nnFile;
            test_dataset = app.TestingDatasetDropDown.Value;
            td_path = ESPConst.PATH_DATASET_PREPROCESSED + test_dataset + "-0" + ESPConst.EXTENSION_DATA;
            num_o = app.NumberofoccurrencesEditField.Value;
            threshold = app.ThresholdEditField.Value;

            x = getClassifierPerformance(nn_path, td_path, "detection", num_o, threshold);
            app.NNOptionsTextArea.Value = "GradientDecayFactor: " + string(x.options.GradientDecayFactor) + newline + ...
                                            "SquaredGradientDecayFactor: " + string(x.options.SquaredGradientDecayFactor) + newline + ...
                                            "Epsilon: " + string(x.options.Epsilon) + newline + ...
                                            "InitialLearnRate: " + string(x.options.InitialLearnRate) + newline + ...
                                            "LearnRateDropFactor: " + string(x.options.LearnRateDropFactor) + newline + ...
                                            "LearnRateDropPeriod: " + string(x.options.LearnRateDropPeriod) + newline + ...
                                            "L2Regularization: " + string(x.options.L2Regularization) + newline + ...
                                            "MaxEpochs: " + string(x.options.MaxEpochs)+ newline + ...
                                            "MiniBatchSize: " + string(x.options.MiniBatchSize);
            app.ValueErrorTextArea.Value = string(x.valError);
            app.AccuracyTextArea.Value = string(x.accuracy);
            app.SensitivityTextArea.Value = string(x.SE);
            app.SpecificityTextArea.Value = string(x.SP);

            app.DetectionLamp.Color = "red";
        end

        % Button pushed function: TrainNNButton
        function TrainNNButtonPushed(app, event)
            app.TrainNNLamp.Color = "green";
            drawnow()


            app.TrainNNLamp.Color = "red";
        end

        % Value changed function: UseautoencoderSwitch
        function UseautoencoderSwitchValueChanged(app, event)
            value = app.UseautoencoderSwitch.Value;
            
            if strcmpi(value,'No')
                app.AutoencoderLabel.Visible = "off";
                app.IconInfoAutoencoder.Visible = "off";
                app.AutoencoderChooseFileButton.Visible = "off";
                app.TrainAEButton.Visible = "off";
                app.TrainAELamp.Visible = "off";
            elseif strcmpi(value,'Yes')
                app.AutoencoderLabel.Visible = "on";
                app.IconInfoAutoencoder.Visible = "on";
                app.AutoencoderChooseFileButton.Visible = "on";
                app.TrainAEButton.Visible = "on";
                app.TrainAELamp.Visible = "on";
            end
        end

        % Button pushed function: NNChooseFileButton
        function NNChooseFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile("*.mat");

            totalPath = string(filePath) + string(fileName);
            if isfile(totalPath)
                app.nnFile = string(filePath) + string(fileName);
                %ESPStatic.setGetNNPath(app.nnFile);
                app.NNChooseFileButton.Text = "Choose file - " + fileName;
                if app.nnFile ~= ""
                    app.PredictionButton.Enable = "on";
                    app.DetectionButton.Enable = "on";
                    app.TrainNNButton.Enable = "on";
                end
            end
        end

        % Button pushed function: AutoencoderChooseFileButton
        function AutoencoderChooseFileButtonPushed(app, event)
            [fileName, filePath] = uigetfile("*.mat");

            totalPath = string(filePath) + string(fileName);
            if isfile(totalPath)
                app.autoencoderFile = string(filePath) + string(fileName);
                %ESPStatic.setGetAutoencoderPath(app.autoencoderFile);
                app.AutoencoderChooseFileButton.Text = "Choose file - " + fileName;
                if app.autoencoderFile ~= ""
                    app.TrainAEButton.Enable = "on";
                end
            end
        end

        % Button pushed function: TrainAEButton
        function TrainAEButtonPushed(app, event)
            app.TrainAELamp.Color = "green";
            drawnow()


            app.TrainAELamp.Color = "red";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UniversityofCoimbraLabel = uilabel(app.Panel);
            app.UniversityofCoimbraLabel.Text = 'University of Coimbra';
            app.MEISIMachineLearning20222023Label = uilabel(app.Panel);
            app.MEISIMachineLearning20222023Label.Text = 'MEI/SI/Machine Learning - 2022/2023';
            app.SanchoAmaralSimes2019217590Label = uilabel(app.Panel);
            app.SanchoAmaralSimes2019217590Label.Text = 'Sancho Amaral Simões, 2019217590';
            app.MiguelAntnioGabrieldeAlmeidaFaria2019216809Label = uilabel(app.Panel);
            app.MiguelAntnioGabrieldeAlmeidaFaria2019216809Label.Text = 'Miguel António Gabriel de Almeida Faria, 2019216809';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.NNOptionsTextAreaLabel = uilabel(app.ResultsPanel);
            app.NNOptionsTextAreaLabel.Text = 'NN Options';
            app.SpecificityTextArea = uitextarea(app.ResultsPanel);
            app.SensitivityTextAreaLabel = uilabel(app.ResultsPanel);
            app.SensitivityTextAreaLabel.Text = 'Sensitivity';
            app.SensitivityTextArea = uitextarea(app.ResultsPanel);
            app.SpecificityTextAreaLabel = uilabel(app.ResultsPanel);
            app.SpecificityTextAreaLabel.Text = 'Specificity';
            app.ValueErrorTextAreaLabel = uilabel(app.ResultsPanel);
            app.ValueErrorTextAreaLabel.Text = 'Value Error';
            app.AccuracyTextAreaLabel = uilabel(app.ResultsPanel);
            app.AccuracyTextAreaLabel.Text = 'Accuracy';
            app.AccuracyTextArea = uitextarea(app.ResultsPanel);
            app.ValueErrorTextArea = uitextarea(app.ResultsPanel);
            app.NNOptionsTextArea = uitextarea(app.ResultsPanel);
            app.PerformanceConfigurationsPanel = uipanel(app.UIFigure);
            app.PerformanceConfigurationsPanel.Title = 'Performance Configurations';
            app.TestingDatasetDropDownLabel = uilabel(app.PerformanceConfigurationsPanel);
            app.TestingDatasetDropDownLabel.Text = 'Testing Dataset';
            app.TestingDatasetDropDown = uidropdown(app.PerformanceConfigurationsPanel);
            app.TestingDatasetDropDown.Items = {'44202', '54802', '63502', '112502'};
            app.TestingDatasetDropDown.Value = '44202';
            app.DetectionButton = uibutton(app.PerformanceConfigurationsPanel, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @DetectionButtonPushed, true);
            app.DetectionButton.Text = 'Detection';
            app.DetectionLamp = uilamp(app.PerformanceConfigurationsPanel);
            app.PredictionButton = uibutton(app.PerformanceConfigurationsPanel, 'push');
            app.PredictionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionButtonPushed, true);
            app.PredictionButton.Text = 'Prediction';
            app.PredictionLamp = uilamp(app.PerformanceConfigurationsPanel);
            app.NumberofoccurrencesEditFieldLabel = uilabel(app.PerformanceConfigurationsPanel);
            app.NumberofoccurrencesEditFieldLabel.Text = 'Number of occurrences';
            app.NumberofoccurrencesEditField = uieditfield(app.PerformanceConfigurationsPanel, 'numeric');
            app.ThresholdEditFieldLabel = uilabel(app.PerformanceConfigurationsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.PerformanceConfigurationsPanel, 'numeric');
            app.NeuralNetworkConfigurationsPanel = uipanel(app.UIFigure);
            app.NeuralNetworkConfigurationsPanel.Title = 'Neural Network Configurations';
            app.ShallowDeepNNLabel = uilabel(app.NeuralNetworkConfigurationsPanel);
            app.ShallowDeepNNLabel.Text = 'Shallow/Deep NN';
            app.NNChooseFileButton = uibutton(app.NeuralNetworkConfigurationsPanel, 'push');
            app.NNChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @NNChooseFileButtonPushed, true);
            app.NNChooseFileButton.Text = 'Choose File - Empty';
            app.IconInfoNN = uiimage(app.NeuralNetworkConfigurationsPanel);
            app.IconInfoNN.ImageSource = fullfile(pathToMLAPP, 'info_icon.png');
            app.UseautoencoderSwitch = uiswitch(app.NeuralNetworkConfigurationsPanel, 'slider');
            app.UseautoencoderSwitch.Items = {'No', 'Yes'};
            app.UseautoencoderSwitch.ValueChangedFcn = createCallbackFcn(app, @UseautoencoderSwitchValueChanged, true);
            app.UseautoencoderSwitch.Value = 'No';
            app.UseautoencoderSwitchLabel = uilabel(app.NeuralNetworkConfigurationsPanel);
            app.UseautoencoderSwitchLabel.Text = 'Use autoencoder';
            app.TrainingDatasetDropDownLabel = uilabel(app.NeuralNetworkConfigurationsPanel);
            app.TrainingDatasetDropDownLabel.Text = 'Training Dataset';
            app.ValidationDatasetDropDownLabel = uilabel(app.NeuralNetworkConfigurationsPanel);
            app.ValidationDatasetDropDownLabel.Text = 'Validation Dataset';
            app.AutoencoderLabel = uilabel(app.NeuralNetworkConfigurationsPanel);
            app.AutoencoderLabel.Text = 'Autoencoder';
            app.IconInfoAutoencoder = uiimage(app.NeuralNetworkConfigurationsPanel);
            app.IconInfoAutoencoder.ImageSource = fullfile(pathToMLAPP, 'info_icon.png');
            app.AutoencoderChooseFileButton = uibutton(app.NeuralNetworkConfigurationsPanel, 'push');
            app.AutoencoderChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @AutoencoderChooseFileButtonPushed, true);
            app.AutoencoderChooseFileButton.Text = 'Choose File - Empty';
            app.TrainingDatasetDropDown = uidropdown(app.NeuralNetworkConfigurationsPanel);
            app.TrainingDatasetDropDown.Items = {'44202', '54802', '63502', '112502'};
            app.TrainingDatasetDropDown.Value = '44202';
            app.ValidationDatasetDropDown = uidropdown(app.NeuralNetworkConfigurationsPanel);
            app.ValidationDatasetDropDown.Items = {'44202', '54802', '63502', '112502'};
            app.ValidationDatasetDropDown.Value = '44202';
            app.TrainNNButton = uibutton(app.NeuralNetworkConfigurationsPanel, 'push');
            app.TrainNNButton.ButtonPushedFcn = createCallbackFcn(app, @TrainNNButtonPushed, true);
            app.TrainNNButton.Text = 'Train NN';
            app.TrainNNLamp = uilamp(app.NeuralNetworkConfigurationsPanel);
            app.TrainAEButton = uibutton(app.NeuralNetworkConfigurationsPanel, 'push');
            app.TrainAEButton.ButtonPushedFcn = createCallbackFcn(app, @TrainAEButtonPushed, true);
            app.TrainAEButton.Text = 'Train AE';
            app.TrainAELamp = uilamp(app.NeuralNetworkConfigurationsPanel);
            app.EpilepsiaeSeizuresPredictorandDetectorLabel = uilabel(app.UIFigure);
            app.EpilepsiaeSeizuresPredictorandDetectorLabel.Text = 'Epilepsiae Seizures Predictor and Detector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end